classdef smlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        Panel                  matlab.ui.container.Panel          % Panel
        Button                 matlab.ui.control.Button           % Spawn V...
        LabelNumericEditField  matlab.ui.control.Label            % Vehicle ID
        VehicleId              matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % Vehicle...
        Vehiclevel             matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % Node ID
        Vehiclenode            matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField         matlab.ui.control.Label            % Vehicle...
        Vehicletype            matlab.ui.control.EditField       
        TabGroup               matlab.ui.container.TabGroup       % Vehicle...
        Tab                    matlab.ui.container.Tab            % Vehicle...
        LabelLinearGauge       matlab.ui.control.Label            % Number ...
        LinearGauge            matlab.ui.control.LinearGauge      % [0 20]
    end


    properties (Access = public)
    ButtonPressed=0;  % Description
    vehicleidvalue=0;
    vehiclevelvalue=0;
    nodevalue=0;
    vehicletypevalue='';
    tabname='';
    selectedTab='';
    selectedtabnum=0;
%     Gauge matlab.ui.control.Gauge
%     LabelGauge matlab.ui.control.Label
%  d = struct('Gauge', matlab.ui.control.Gauge);
 gauges= repmat(struct('Gauge', matlab.ui.control.Gauge), [1 20]);
        %LabelSpinner matlab.ui.control.Label 
         % LabelNumericEditField = 0;
    end

    properties (Access = private)
     %    LabelSpinner matlab.ui.control.Label  % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % disp('Hello');
            
        end

        % Button button pushed function
        function SpawnCarButtonPushed(app)
            %matlab.ui.control.Gauge.Value=10;
                 app.ButtonPressed=1;
                 
                 app.tabname=strcat( 'Vehicle ID:', int2str(app.vehicleidvalue));
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = app.tabname;
            
             app.gauges(app.vehicleidvalue).Gauge = uigauge(app.Tab, 'circular');    
             app.gauges(app.vehicleidvalue).Gauge.Limits = [0 20];
             app.gauges(app.vehicleidvalue).Gauge.MajorTicks = [0 4 8 12 16 20];
             app.gauges(app.vehicleidvalue).Gauge.FontWeight = 'bold';
             app.gauges(app.vehicleidvalue).Gauge.Position = [23 191 120 120];
            
%             app.tabname='Vehicle ID:';
            
%             app.Gauge = uigauge(app.Tab, 'circular');    
%             app.Gauge.Limits = [0 20];
%             app.Gauge.MajorTicks = [0 4 8 12 16 20];
%             app.Gauge.FontWeight = 'bold';
%             app.Gauge.Position = [23 191 120 120];  
%             app.Gauge.Tag=app.tabname;
            
        end

        % VehicleId value changed function
        function VehicleIdValueChanged2(app)
            app.vehicleidvalue = app.VehicleId.Value;
            
        end

        % Vehiclevel value changed function
        function VehiclevelValueChanged(app)
            app.vehiclevelvalue = app.Vehiclevel.Value;
            
        end

        % Vehiclenode value changed function
        function VehiclenodeValueChanged(app)
            app.nodevalue = app.Vehiclenode.Value;
            
        end

        % Vehicletype value changed function
        function VehicletypeValueChanged(app)
            app.vehicletypevalue = app.Vehicletype.Value;
            
        end

        % TabGroup selection changed function
        function TabGroupSelectionChanged(app)
%             app.selectedTab = app.TabGroup.SelectedTab;
%             
% %             disp('tab changed');
%             
%              if(strcmp(app.TabGroup.SelectedTab.Title(1:10),'Vehicle ID'))
%                 
%                 %                k=strfind(app.selectedTab,':'); 
%                 app.selectedtabnum=str2num(app.TabGroup.SelectedTab.Title(12:end));
%                 
%                 
% %              app.Gauge(app.selectedtabnum) = uigauge(app.Tab, 'circular');    
% %              app.Gauge(app.selectedtabnum).Limits = [0 20];
% %              app.Gauge(app.selectedtabnum).MajorTicks = [0 4 8 12 16 20];
% %              app.Gauge(app.selectedtabnum).FontWeight = 'bold';
% %              app.Gauge(app.selectedtabnum).Position = [23 191 120 120];   
% 
% %              app.gauges(app.selectedtabnum).Gauge = uigauge(app.Tab, 'circular');    
% %              app.gauges(app.selectedtabnum).Gauge.Limits = [0 20];
% %              app.gauges(app.selectedtabnum).Gauge.MajorTicks = [0 4 8 12 16 20];
% %              app.gauges(app.selectedtabnum).Gauge.FontWeight = 'bold';
% %              app.gauges(app.selectedtabnum).Gauge.Position = [23 191 120 120];
% 
%              else
% %                
% %                           disp('Default tab');
%                            app.selectedtabnum=0;
%                
%              end
            
        end

        % TabGroup selection changed function
        function TabGroupSelectionChanged2(app)
                       app.selectedTab = app.TabGroup.SelectedTab;
            
%             disp('tab changed');
            
             if(strcmp(app.TabGroup.SelectedTab.Title(1:10),'Vehicle ID'))
                
                %                k=strfind(app.selectedTab,':'); 
                app.selectedtabnum=str2num(app.TabGroup.SelectedTab.Title(12:end));
                
                
%              app.Gauge(app.selectedtabnum) = uigauge(app.Tab, 'circular');    
%              app.Gauge(app.selectedtabnum).Limits = [0 20];
%              app.Gauge(app.selectedtabnum).MajorTicks = [0 4 8 12 16 20];
%              app.Gauge(app.selectedtabnum).FontWeight = 'bold';
%              app.Gauge(app.selectedtabnum).Position = [23 191 120 120];   

%              app.gauges(app.selectedtabnum).Gauge = uigauge(app.Tab, 'circular');    
%              app.gauges(app.selectedtabnum).Gauge.Limits = [0 20];
%              app.gauges(app.selectedtabnum).Gauge.MajorTicks = [0 4 8 12 16 20];
%              app.gauges(app.selectedtabnum).Gauge.FontWeight = 'bold';
%              app.gauges(app.selectedtabnum).Gauge.Position = [23 191 120 120];

             else
%                
%                           disp('Default tab');
                           app.selectedtabnum=0;
               
             end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.Units = 'pixels';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @SpawnCarButtonPushed);
            app.Button.Text = 'Spawn Vehicle';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Vehicle ID';
            app.VehicleId = uieditfield(app.Panel, 'numeric');
            app.VehicleId.ValueChangedFcn = createCallbackFcn(app, @VehicleIdValueChanged2);
            app.VehicleId.Limits = [0 Inf];
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'Vehicle Velocity';
            app.Vehiclevel = uieditfield(app.Panel, 'numeric');
            app.Vehiclevel.ValueChangedFcn = createCallbackFcn(app, @VehiclevelValueChanged);
            app.Vehiclevel.Limits = [0 Inf];
            app.LabelNumericEditField3 = uilabel(app.Panel);
            app.LabelNumericEditField3.Text = 'Node ID';
            app.Vehiclenode = uieditfield(app.Panel, 'numeric');
            app.Vehiclenode.ValueChangedFcn = createCallbackFcn(app, @VehiclenodeValueChanged);
            app.LabelEditField = uilabel(app.Panel);
            app.LabelEditField.Text = 'Vehicle Type';
            app.Vehicletype = uieditfield(app.Panel, 'text');
            app.Vehicletype.ValueChangedFcn = createCallbackFcn(app, @VehicletypeValueChanged);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged2);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Vehicle Status';
            app.LabelLinearGauge = uilabel(app.UIFigure);
            app.LabelLinearGauge.Text = 'Number of Vehicles';
            app.LinearGauge = uigauge(app.UIFigure, 'linear');
            app.LinearGauge.Limits = [0 20];
            app.LinearGauge.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
        end
    end

    methods (Access = public)

        % Construct app
        function app = smlapp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end